classdef reconsitution_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        MipNumber              matlab.ui.control.NumericEditField
        RunButton              matlab.ui.control.Button
        Label_2                matlab.ui.control.Label
        SourceFolder           matlab.ui.control.EditField
        Label_3                matlab.ui.control.Label
        Mean                   matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        Std                    matlab.ui.control.NumericEditField
        BrowseButton           matlab.ui.control.Button
        FlipZ                  matlab.ui.control.CheckBox
        Label_5                matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        FinishLampLabel        matlab.ui.control.Label
        FinishLamp             matlab.ui.control.Lamp
        WViewCheckBox          matlab.ui.control.CheckBox
        GPUDropDownLabel       matlab.ui.control.Label
        GPUDropDown            matlab.ui.control.DropDown
        Label_7                matlab.ui.control.Label
        ParField               matlab.ui.control.NumericEditField
        Image                  matlab.ui.control.Image
        RotateBox              matlab.ui.control.CheckBox
        umLabel                matlab.ui.control.Label
        PixelSize              matlab.ui.control.NumericEditField
        H5                     matlab.ui.control.CheckBox
        Segment                matlab.ui.control.CheckBox
        Analysis               matlab.ui.control.CheckBox
        Label_8                matlab.ui.control.Label
        Magnification          matlab.ui.control.NumericEditField
        Label_9                matlab.ui.control.Label
        Theta                  matlab.ui.control.NumericEditField
        binningEditFieldLabel  matlab.ui.control.Label
        Binning                matlab.ui.control.NumericEditField
        Label_10               matlab.ui.control.Label
        ViewCenter             matlab.ui.control.NumericEditField
        NewSystem              matlab.ui.control.CheckBox
        CKPT                   matlab.ui.control.EditField
        BrowseButton_2         matlab.ui.control.Button
        Label_CKPT             matlab.ui.control.Label
        Label_Tip              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            main_path = fullfile(pwd, 'main');
            addpath(genpath(main_path))
            num = gpuDeviceCount;
            app.GPUDropDown.Items = {'不使用'};
            app.GPUDropDown.ItemsData = {'0'};
            for i = 1:num
                app.GPUDropDown.Items = [app.GPUDropDown.Items, num2str(i)];
                app.GPUDropDown.ItemsData = [app.GPUDropDown.ItemsData, num2str(i)];
            end
            app.GPUDropDown.Value = '0';
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            try
                app.SourceFolder.Value = uigetdir();
            catch
                
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.FinishLamp.Color = [0.50,0.50,0.50];
            
            if ~isfolder(app.SourceFolder.Value)
                warndlg('Please choose sorurse floder','warning');
                return
            end
            if app.Segment.Value && ~isfile(app.CKPT.Value)
                warndlg('ckpt does not exist','warning');
                return
            end
                
            clc
            
            config = struct();
            config.root = app.SourceFolder.Value;
            config.test_num = app.MipNumber.Value;
            config.mean = app.Mean.Value;
            config.std = app.Std.Value;
            config.flipz = app.FlipZ.Value;
            config.wview = app.WViewCheckBox.Value;
            config.rotate = app.RotateBox.Value;
            config.pixelsize = app.PixelSize.Value/app.Magnification.Value;
            config.theta = app.Theta.Value/180*pi;
            config.h5 = app.H5.Value;
            config.binning = app.Binning.Value;
            config.view_center = app.ViewCenter.Value;
            config.new_system = app.NewSystem.Value;
            config.pass = 0;
            
            config.segment = app.Segment.Value;
            config.ckpt = app.CKPT.Value;
            config.gpu_num = gpuDeviceCount;
            
            config.analysis = app.Analysis.Value;
            
            configure(config);
            
            app.FinishLamp.Color = [0.00,1.00,0.00];

        end

        % Value changed function: Segment
        function SegmentValueChanged(app, event)
            value = app.Segment.Value;
            if value
                app.H5.Value = true;
                app.BrowseButton_2.Visible = true;
                app.CKPT.Visible = true;
                app.Label_CKPT.Visible = true;
            else
                app.BrowseButton_2.Visible = false;
                app.CKPT.Visible = false;
                app.Label_CKPT.Visible = false;
            end
            
        end

        % Value changed function: Analysis
        function AnalysisValueChanged(app, event)
            value = app.Analysis.Value;
            if value
                app.H5.Value = true;
                app.Segment.Value = true;
                app.Label_Tip.Visible = true;
            else
                app.Label_Tip.Visible = false;
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            try
                app.CKPT.Value = uigetdir();
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '测试体积数';
            app.MipNumber = uieditfield(app.UIFigure, 'numeric');
            app.MipNumber.Limits = [0 Inf];
            app.MipNumber.RoundFractionalValues = 'on';
            app.MipNumber.Value = Inf;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '源文件夹';
            app.SourceFolder = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '高斯填充：均值';
            app.Mean = uieditfield(app.UIFigure, 'numeric');
            app.Mean.Limits = [0 Inf];
            app.Mean.Value = 389;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '标准差';
            app.Std = uieditfield(app.UIFigure, 'numeric');
            app.Std.Limits = [0 Inf];
            app.Std.Value = 2.6;
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FlipZ = uicheckbox(app.UIFigure);
            app.FlipZ.Text = '偶数体积翻转';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '使用说明';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'源文件夹下，请以''v_split_slice_step_...''格式命名文件夹。其中v_为标识符；split为一组数据中扫描的视野数；slice为每个视野拍摄的张数；step为扫描步长；命名末尾''_''后可添加任意注释。'; ''; '''v_...''文件夹下，请以''H_...''或’K_...''开头的文件夹存放原始数据。相同位置相同参数拍摄的数据应放在同一''v_...''文件夹下，建议按拍摄顺序命名为 ‘H_1_注释’ 的格式，以确定拍摄先后顺序。'; ''; '勾选预览的情况下，将使用快速矫正算法，仅保存两个方向的最大强度投影。保存路径在''v_...''文件夹下，如‘MIP_ROI_1/MIPxy_488'''; ''; '在有用的MIP文件夹下新建若干个ROI文件夹’ROI_W_H_D_x_y_z''，在非预览模式下保存裁剪后的ROI'};
            app.FinishLampLabel = uilabel(app.UIFigure);
            app.FinishLampLabel.Text = 'Finish';
            app.FinishLamp = uilamp(app.UIFigure);
            app.WViewCheckBox = uicheckbox(app.UIFigure);
            app.WViewCheckBox.Text = 'W View';
            app.WViewCheckBox.Value = true;
            app.GPUDropDownLabel = uilabel(app.UIFigure);
            app.GPUDropDownLabel.Visible = 'off';
            app.GPUDropDownLabel.Text = 'GPU';
            app.GPUDropDown = uidropdown(app.UIFigure);
            app.GPUDropDown.Items = {'不使用'};
            app.GPUDropDown.ItemsData = {'0'};
            app.GPUDropDown.Editable = 'on';
            app.GPUDropDown.Visible = 'off';
            app.GPUDropDown.Value = '0';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Visible = 'off';
            app.Label_7.Text = '线程数';
            app.ParField = uieditfield(app.UIFigure, 'numeric');
            app.ParField.Editable = 'off';
            app.ParField.Visible = 'off';
            app.ParField.Value = 12;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'example.jpg';
            app.RotateBox = uicheckbox(app.UIFigure);
            app.RotateBox.Text = '转平';
            app.RotateBox.Value = true;
            app.umLabel = uilabel(app.UIFigure);
            app.umLabel.Text = '像元尺寸/um';
            app.PixelSize = uieditfield(app.UIFigure, 'numeric');
            app.PixelSize.Limits = [1 Inf];
            app.PixelSize.Value = 6.5;
            app.H5 = uicheckbox(app.UIFigure);
            app.H5.Text = '存储为.h5';
            app.H5.Value = true;
            app.Segment = uicheckbox(app.UIFigure);
            app.Segment.ValueChangedFcn = createCallbackFcn(app, @SegmentValueChanged, true);
            app.Segment.Text = '网络分割';
            app.Analysis = uicheckbox(app.UIFigure);
            app.Analysis.ValueChangedFcn = createCallbackFcn(app, @AnalysisValueChanged, true);
            app.Analysis.Text = '数据分析';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '系统放大率';
            app.Magnification = uieditfield(app.UIFigure, 'numeric');
            app.Magnification.Value = 62.2;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '探测角度/°';
            app.Theta = uieditfield(app.UIFigure, 'numeric');
            app.Theta.Value = 30;
            app.binningEditFieldLabel = uilabel(app.UIFigure);
            app.binningEditFieldLabel.Text = '数字binning';
            app.Binning = uieditfield(app.UIFigure, 'numeric');
            app.Binning.Value = 2;
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '视野中心';
            app.ViewCenter = uieditfield(app.UIFigure, 'numeric');
            app.ViewCenter.Value = 280;
            app.NewSystem = uicheckbox(app.UIFigure);
            app.NewSystem.Text = '新系统参数';
            app.NewSystem.Value = true;
            app.CKPT = uieditfield(app.UIFigure, 'text');
            app.CKPT.Visible = 'off';
            app.CKPT.Value = 'I:\CAR_T\code\segment\train_3-4\ckpt\best_checkpoint.pytorch';
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Visible = 'off';
            app.BrowseButton_2.Text = 'Browse';
            app.Label_CKPT = uilabel(app.UIFigure);
            app.Label_CKPT.Visible = 'off';
            app.Label_CKPT.Text = '预训练文件';
            app.Label_Tip = uilabel(app.UIFigure);
            app.Label_Tip.Visible = 'off';
            app.Label_Tip.Text = '建议使用另一个Matlab程序运行分析，可提高运算效率';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reconsitution_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end